classdef app_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        InputsPanel              matlab.ui.container.Panel
        threshold_edit_field     matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel  matlab.ui.control.Label
        run_detection_button     matlab.ui.control.Button
        open_image_button        matlab.ui.control.Button
        output_step_text         matlab.ui.control.Label
        input_filename_text      matlab.ui.control.Label
        InputImagePanel          matlab.ui.container.Panel
        input_image_axes         matlab.ui.control.UIAxes
        ProcessedImagePanel      matlab.ui.container.Panel
        processed_image_axes     matlab.ui.control.UIAxes
        DetectedplatePanel       matlab.ui.container.Panel
        detected_plate_axes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        inputimage % the input image loaded from the explorer
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: open_image_button
        function open_image_buttonButtonPushed(app, event)
           
            % assign axes variables
            app.input_image_axes = app.input_image_axes;
            app.processed_image_axes = app.processed_image_axes;

            app.input_filename_text.Text ='Processing...';
            [filename, path] = uigetfile({'*.jpg'; '*.png'}, 'File Selector', '..\dataset\12MP');           % save selected image path
            if (path == 0)
                return
            end

            fullpath = strcat(path, filename);                            % concatenate path- with filename
            %fullpath = 'C:\Users\Gabriel\Desktop\Projects\MATLAB\plate_recognition\license_plate_recognition\dataset\6MP\Wien2.jpg'; % TODO temporary
            %filename = 'Burgenland1.jpg';
            app.inputimage =  imread(fullpath);                            % read the image from file
            app.input_filename_text.Text = filename;
            app.run_detection_button.Enable = true;
            imshow(app.inputimage, 'Parent', app.input_image_axes);                    % plot the image
            
        end

        % Button pushed function: run_detection_button
        function run_detection_buttonButtonPushed(app, event)
            
            app.open_image_button.Enable = false;
            app.run_detection_button.Enable = false;

            main(app.inputimage);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'APR_GUI';
            app.figure1.Tag = 'figure1';
            app.DetectedplatePanel = uipanel(app.figure1);
            app.DetectedplatePanel.Title = 'Detected plate';
            app.detected_plate_axes = uiaxes(app.DetectedplatePanel);
            app.detected_plate_axes.Tag = 'detected_plate_axes';
            app.detected_plate_axes.Visible = 'off';
            app.ProcessedImagePanel = uipanel(app.figure1);
            app.ProcessedImagePanel.Title = 'Processed Image';
            app.processed_image_axes = uiaxes(app.ProcessedImagePanel);
            app.processed_image_axes.Tag = 'processed_image_axes';
            app.processed_image_axes.Visible = 'off';
            app.InputImagePanel = uipanel(app.figure1);
            app.InputImagePanel.Title = 'Input Image';
            app.input_image_axes = uiaxes(app.InputImagePanel);
            app.input_image_axes.Tag = 'input_image_axes';
            app.input_image_axes.Visible = 'off';
            app.InputsPanel = uipanel(app.figure1);
            app.InputsPanel.Title = 'Inputs';
            app.input_filename_text = uilabel(app.InputsPanel);
            app.input_filename_text.Tag = 'input_filename_text';
            app.input_filename_text.Text = '';
            app.output_step_text = uilabel(app.InputsPanel);
            app.output_step_text.Tag = 'output_step_text';
            app.output_step_text.Text = '';
            app.open_image_button = uibutton(app.InputsPanel, 'push');
            app.open_image_button.ButtonPushedFcn = createCallbackFcn(app, @open_image_buttonButtonPushed, true);
            app.open_image_button.Tag = 'open_image_button';
            app.open_image_button.Text = 'Open Image';
            app.run_detection_button = uibutton(app.InputsPanel, 'push');
            app.run_detection_button.ButtonPushedFcn = createCallbackFcn(app, @run_detection_buttonButtonPushed, true);
            app.run_detection_button.Tag = 'run_detection_button';
            app.run_detection_button.Enable = 'off';
            app.run_detection_button.Text = 'Run detection';
            app.ThresholdEditFieldLabel = uilabel(app.InputsPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.threshold_edit_field = uieditfield(app.InputsPanel, 'numeric');
            app.threshold_edit_field.Limits = [0.001 0.1];
            app.threshold_edit_field.Tag = 'threshold_edit_field';
            app.threshold_edit_field.Value = 0.025;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_GUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end